classdef MOOTuning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MOOTuningUIFigure          matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        PlantparametersLabel       matlab.ui.control.Label
        kEditFieldLabel            matlab.ui.control.Label
        kEditField                 matlab.ui.control.NumericEditField
        Image                      matlab.ui.control.Image
        TEditFieldLabel            matlab.ui.control.Label
        TEditField                 matlab.ui.control.NumericEditField
        aEditFieldLabel            matlab.ui.control.Label
        aEditField                 matlab.ui.control.NumericEditField
        LEditFieldLabel            matlab.ui.control.Label
        LEditField                 matlab.ui.control.NumericEditField
        UIAxes2                    matlab.ui.control.UIAxes
        CenterPanel                matlab.ui.container.Panel
        MOOTuningLabel             matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        ServoresponseCheckBox      matlab.ui.control.CheckBox
        InputdisturbanceregulationresponseCheckBox  matlab.ui.control.CheckBox
        OutputdisturbanceregullationresponseCheckBox  matlab.ui.control.CheckBox
        FinaltimeEditFieldLabel    matlab.ui.control.Label
        FinaltimeEditField         matlab.ui.control.NumericEditField
        KpLabel                    matlab.ui.control.Label
        TiLabel                    matlab.ui.control.Label
        TdLabel                    matlab.ui.control.Label
        betaLabel                  matlab.ui.control.Label
        KpValueLabel               matlab.ui.control.Label
        TiValueLabel               matlab.ui.control.Label
        TdValueLabel               matlab.ui.control.Label
        betaValueLabel             matlab.ui.control.Label
        ControleffortCheckBox      matlab.ui.control.CheckBox
        JdiLabel                   matlab.ui.control.Label
        JdoLabel                   matlab.ui.control.Label
        JdiValueLabel              matlab.ui.control.Label
        JdoValueLabel              matlab.ui.control.Label
        JrLabel                    matlab.ui.control.Label
        MsLabel                    matlab.ui.control.Label
        JrValueLabel               matlab.ui.control.Label
        MsValueLabel               matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        JdiSliderLabel             matlab.ui.control.Label
        JdiSlider                  matlab.ui.control.Slider
        JdoSliderLabel             matlab.ui.control.Label
        JdoSlider                  matlab.ui.control.Slider
        JrSliderLabel              matlab.ui.control.Label
        JrSlider                   matlab.ui.control.Slider
        ControllertuningLabel      matlab.ui.control.Label
        ExportTuningButton         matlab.ui.control.Button
        DisturbancesDropDownLabel  matlab.ui.control.Label
        DisturbancesDropDown       matlab.ui.control.DropDown
        MsDropDownLabel            matlab.ui.control.Label
        MsDropDown                 matlab.ui.control.DropDown
        LabelJdiSlider             matlab.ui.control.Label
        LabelJdoSlider             matlab.ui.control.Label
        LabelJrSlider              matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Results = struct('Kp',0.0,'Ti',0.0,'Td',0.0,'beta',0.0,'alpha',0.1, 'gamma',0,...
            'Ms',0.0,'Jdi',0.0,'Jdo',0.0,'Jr',0.0,'k',0.0,'T',0.0,'L',0.0,'a',0.0); % Structure that holds the results
    end
    
    methods (Access = private)
        
        function plotStepResp(app)
            k = app.kEditField.Value;
            T = app.TEditField.Value;
            L = app.LEditField.Value;
            a = app.aEditField.Value;
            F = tf(k,[a*T^2,(1+a)*T,1]);
            F.iodelay = L;
            [y,t] = step(F);
            plot(app.UIAxes2,t,y)
            app.Results.k = k;
            app.Results.T = T;
            app.Results.L = L;
            app.Results.a = a;
        end
        
        function [kp,zi,zd,B]=interpolarControl3Fun(app,t0,a,Ms,Jdimax,Jdomax,Jrmax)
            t0Buscar=[floor(t0/0.1),ceil(t0/0.1)]*0.1;
            aBuscar=[floor(a/0.1),ceil(a/0.1)]*0.1;
            %
            %Primera interpolacion: a con t0 contante
            if aBuscar(1) ~= aBuscar(2)
                if t0Buscar(1) ~= t0Buscar(2)
                    [JdiVec_t01_a1,JdoVec_t01_a1,JrVec_t01_a1,KpVec_t01_a1,TiVec_t01_a1,TdVec_t01_a1,betaVec_t01_a1]=seleccionar(app,t0Buscar(1),aBuscar(1),Ms);
                    [JdiVec_t02_a1,JdoVec_t02_a1,JrVec_t02_a1,KpVec_t02_a1,TiVec_t02_a1,TdVec_t02_a1,betaVec_t02_a1]=seleccionar(app,t0Buscar(2),aBuscar(1),Ms);
                    Param_t01_a1=buscar3Fun(app,JdiVec_t01_a1,JdoVec_t01_a1,JrVec_t01_a1,KpVec_t01_a1,TiVec_t01_a1,TdVec_t01_a1,betaVec_t01_a1,Jdimax,Jdomax,Jrmax);
                    Param_t02_a1=buscar3Fun(app,JdiVec_t02_a1,JdoVec_t02_a1,JrVec_t02_a1,KpVec_t02_a1,TiVec_t02_a1,TdVec_t02_a1,betaVec_t02_a1,Jdimax,Jdomax,Jrmax);
                    %
                    [JdiVec_t01_a2,JdoVec_t01_a2,JrVec_t01_a2,KpVec_t01_a2,TiVec_t01_a2,TdVec_t01_a2,betaVec_t01_a2]=seleccionar(app,t0Buscar(1),aBuscar(2),Ms);
                    [JdiVec_t02_a2,JdoVec_t02_a2,JrVec_t02_a2,KpVec_t02_a2,TiVec_t02_a2,TdVec_t02_a2,betaVec_t02_a2]=seleccionar(app,t0Buscar(2),aBuscar(2),Ms);
                    Param_t01_a2=buscar3Fun(app,JdiVec_t01_a2,JdoVec_t01_a2,JrVec_t01_a2,KpVec_t01_a2,TiVec_t01_a2,TdVec_t01_a2,betaVec_t01_a2,Jdimax,Jdomax,Jrmax);
                    Param_t02_a2=buscar3Fun(app,JdiVec_t02_a2,JdoVec_t02_a2,JrVec_t02_a2,KpVec_t02_a2,TiVec_t02_a2,TdVec_t02_a2,betaVec_t02_a2,Jdimax,Jdomax,Jrmax);
                    %
                    Param_t01=interpSimple(app,aBuscar(1),Param_t01_a1,aBuscar(2),Param_t01_a2,a);
                    Param_t02=interpSimple(app,aBuscar(1),Param_t02_a1,aBuscar(2),Param_t02_a2,a);
                    Param=interpSimple(app,t0Buscar(1),Param_t01,t0Buscar(2),Param_t02,t0);
                else
                    [JdiVec_t01_a1,JdoVec_t01_a1,JrVec_t01_a1,KpVec_t01_a1,TiVec_t01_a1,TdVec_t01_a1,betaVec_t01_a1]=seleccionar(app,t0Buscar(1),aBuscar(1),Ms);
                    [JdiVec_t01_a2,JdoVec_t01_a2,JrVec_t01_a2,KpVec_t01_a2,TiVec_t01_a2,TdVec_t01_a2,betaVec_t01_a2]=seleccionar(app,t0Buscar(1),aBuscar(2),Ms);
                    Param_t01_a1=buscar3Fun(app,JdiVec_t01_a1,JdoVec_t01_a1,JrVec_t01_a1,KpVec_t01_a1,TiVec_t01_a1,TdVec_t01_a1,betaVec_t01_a1,Jdimax,Jdomax,Jrmax);
                    Param_t01_a2=buscar3Fun(app,JdiVec_t01_a2,JdoVec_t01_a2,JrVec_t01_a2,KpVec_t01_a2,TiVec_t01_a2,TdVec_t01_a2,betaVec_t01_a2,Jdimax,Jdomax,Jrmax);
                    Param=interpSimple(app,aBuscar(1),Param_t01_a1,aBuscar(2),Param_t01_a2,a);
                end
            else
                if t0Buscar(1) ~= t0Buscar(2)
                    [JdiVec_t01_a1,JdoVec_t01_a1,JrVec_t01_a1,KpVec_t01_a1,TiVec_t01_a1,TdVec_t01_a1,betaVec_t01_a1]=seleccionar(app,t0Buscar(1),aBuscar(1),Ms);
                    [JdiVec_t02_a1,JdoVec_t02_a1,JrVec_t02_a1,KpVec_t02_a1,TiVec_t02_a1,TdVec_t02_a1,betaVec_t02_a1]=seleccionar(app,t0Buscar(2),aBuscar(1),Ms);
                    Param_t01_a1=buscar3Fun(app,JdiVec_t01_a1,JdoVec_t01_a1,JrVec_t01_a1,KpVec_t01_a1,TiVec_t01_a1,TdVec_t01_a1,betaVec_t01_a1,Jdimax,Jdomax,Jrmax);
                    Param_t02_a1=buscar3Fun(app,JdiVec_t02_a1,JdoVec_t02_a1,JrVec_t02_a1,KpVec_t02_a1,TiVec_t02_a1,TdVec_t02_a1,betaVec_t02_a1,Jdimax,Jdomax,Jrmax);
                    Param=interpSimple(app,t0Buscar(1),Param_t01_a1,t0Buscar(2),Param_t02_a1,t0);
                else
                    [JdiVec_t01_a1,JdoVec_t01_a1,JrVec_t01_a1,KpVec_t01_a1,TiVec_t01_a1,TdVec_t01_a1,betaVec_t01_a1]=seleccionar(app,t0Buscar(1),aBuscar(1),Ms);
                    Param=buscar3Fun(app,JdiVec_t01_a1,JdoVec_t01_a1,JrVec_t01_a1,KpVec_t01_a1,TiVec_t01_a1,TdVec_t01_a1,betaVec_t01_a1,Jdimax,Jdomax,Jrmax);
                end
            end
            if isempty(Param)
                kp = nan;
                zi = nan;
                zd = nan;
                B = nan;
            else
                kp = Param(1);
                zi = Param(2);
                zd = Param(3);
                B = Param(4);
            end
            
        end
        
        function yi=interpSimple(~,x1,y1,x2,y2,xi)
            if isempty(y1) || isempty(y2)
                yi = [];
            else
                yi=y1+(y2-y1)./(x2-x1).*(xi-x1);
            end
        end
        
        function [Param,Jmin]=buscar3Fun(~,JdiVec,JdoVec,JrVec,KpVec,TiVec,TdVec,betaVec,Jdimax,Jdomax,Jrmax)
            % [ParamContr, Jdi, Jdo, Jr]=buscar(to,a,Ms,Jdimax,Jdomax)
            % Encuentra el controlador que cumpla con Jdimax, Jdomax y Jrmax (valores
            % normalizados). Solo uno de ellos puede ser cero a la vez.
            %
            % Universidad de Costa Rica
            % Enero 2020
            
            %--------------------------------------------------------------------------
            %
            % Cargamos el archivo
            %
            %--------------------------------------------------------------------------
            if (Jdimax == min([Jdimax,Jdomax,Jrmax])) %&& Jdomax~=0 && Jrmax~=0 %Jdimax==0 && Jdomax~=0 && Jrmax~=0
                vec1=JdoVec<=Jdomax;
                vec2=JrVec<=Jrmax;
                i=and(vec1,vec2);
                KpJ=KpVec(i);
                TiJ=TiVec(i);
                TdJ=TdVec(i);
                betaJ = betaVec(i);
                J=JdiVec(i);
                [Jmin,k]=min(J);
                if Jmin <= Jdimax
                    Kp=KpJ(k);
                    Ti=TiJ(k);
                    Td=TdJ(k);
                    beta=betaJ(k);
                    Param=[Kp;Ti;Td;beta];
                else
                    Param = [];
                end
                
            elseif (Jdomax == min([Jdimax,Jdomax,Jrmax])) %&& Jdimax~=0 && Jrmax~=0
                vec1=JdiVec<=Jdimax;
                vec2=JrVec<=Jrmax;
                i=and(vec1,vec2);
                KpJ=KpVec(i);
                TiJ=TiVec(i);
                TdJ=TdVec(i);
                betaJ = betaVec(i);
                J=JdoVec(i);
                [Jmin,k]=min(J);
                if Jmin <= Jdomax
                    Kp=KpJ(k);
                    Ti=TiJ(k);
                    Td=TdJ(k);
                    beta=betaJ(k);
                    Param=[Kp;Ti;Td;beta];
                else
                    Param = [];
                end
                
            elseif (Jrmax == min([Jdimax,Jdomax,Jrmax])) %&& Jdimax~=0 && Jdomax~=0
                vec1=JdiVec<=Jdimax;
                vec2=JdoVec<=Jdomax;
                i=and(vec1,vec2);
                KpJ=KpVec(i);
                TiJ=TiVec(i);
                TdJ=TdVec(i);
                betaJ = betaVec(i);
                J=JrVec(i);
                [Jmin,k]=min(J);
                if Jmin<= Jrmax
                    Kp=KpJ(k);
                    Ti=TiJ(k);
                    Td=TdJ(k);
                    beta=betaJ(k);
                    Param=[Kp;Ti;Td;beta];
                else
                    Param = [];
                end
                
            else
                vec1=JdoVec<=Jdomax;
                vec2=JrVec<=Jrmax;
                i=and(vec1,vec2);
                KpJ=KpVec(i);
                TiJ=TiVec(i);
                TdJ=TdVec(i);
                betaJ = betaVec(i);
                J=JdiVec(i);
                [Jmin,k]=min(J);
                if Jmin <= Jdimax
                    Kp=KpJ(k);
                    Ti=TiJ(k);
                    Td=TdJ(k);
                    beta=betaJ(k);
                    Param=[Kp;Ti;Td;beta];
                else
                    Param = [];
                end
            end
        end
        
        function [JdiVec,JdoVec,JrVec,KpVec,TiVec,TdVec,betaVec]=seleccionar(app,t0,a,Ms)
            % Devuelve los vectores de busqueda, de acuerdo con las caracteristicas de
            % la planta
            NumFun = app.DisturbancesDropDown.Value;
            if strcmp(NumFun,'Jdi and Jr')
                InicioNombre = 'Pareto2Fun';
                %FolderNombre = '2fun';%Comentar al empaquetar
            else
                InicioNombre = 'Pareto3Fun';
                %FolderNombre = '3fun';%Comentar al empaquetar
            end
            archivo=[InicioNombre,'_a',num2str(a),'_to',num2str(t0),'_ms',num2str(Ms),'.csv'];
            %cd(FolderNombre) %Comentar al empaquetar
            M=csvread(archivo,1,0);
            %cd ..%Comentar al empaquetar
            if strcmp(NumFun,'Jdi and Jr')
                JdiVec=M(:,9);
                JdoVec=[];
                JrVec=M(:,10);
                KpVec=M(:,3);
                TiVec=M(:,4);
                TdVec=M(:,5);
                betaVec=M(:,6);
            else
                JdiVec=M(:,10);
                JdoVec=M(:,11);
                JrVec=M(:,12);
                KpVec=M(:,3);
                TiVec=M(:,4);
                TdVec=M(:,5);
                betaVec=M(:,6);
            end
        end
        
        function updateTuning(app)
            % Obtencion de los parametros de la planta
            k = app.kEditField.Value;
            T = app.TEditField.Value;
            L = app.LEditField.Value;
            a = app.aEditField.Value;
            app.Results.k = k;
            app.Results.T = T;
            app.Results.L = L;
            app.Results.a = a;
            %
            Jdimax = app.JdiSlider.Value;
            Jdomax = app.JdoSlider.Value;
            Jrmax = app.JrSlider.Value;
            switch app.MsDropDown.Value
                case '> 2.0'
                    Ms = 10;
                case '2.0'
                    Ms = 2;
                case '1.8'
                    Ms = 1.8;
                case ' 1.6'
                    Ms = 1.6;
                case '1.4'
                    Ms = 1.4;
                otherwise
                    Ms = 2;
            end
            %
            t0 = L/T;
            if 0.1 <= t0 && t0 < 2
                if strcmp(app.DisturbancesDropDown.Value,'Jdi and Jr')
                    [zkp,zti,ztd,beta]=interpolarControl2Fun(app,t0,a,Ms,Jdimax,Jrmax);
                else
                    [zkp,zti,ztd,beta]=interpolarControl3Fun(app,t0,a,Ms,Jdimax,Jdomax,Jrmax);
                end
                if isnan(zkp) ||  isnan(zti) || isnan(ztd) || isnan(beta)
                    msgbox('Desired values combination is not in the Pareto frontier, try other combination', 'Error','error');
                else
                    Kp = zkp/k;
                    Ti = zti*T;
                    Td = ztd*T;
                    app.KpValueLabel.Text = num2str(Kp);
                    app.TiValueLabel.Text = num2str(Ti);
                    app.TdValueLabel.Text = num2str(Td);
                    app.betaValueLabel.Text = num2str(beta);
                    app.Results.Kp = Kp;
                    app.Results.Ti = Ti;
                    app.Results.Td = Td;
                    app.Results.beta = beta;
                    tfinal = app.FinaltimeEditField.Value;
                    JdiPlot = app.InputdisturbanceregulationresponseCheckBox.Value;
                    JdoPlot = app.OutputdisturbanceregullationresponseCheckBox.Value;
                    JrPlot = app.ServoresponseCheckBox.Value;
                    ploter(app,k,T,a,L,Kp,Ti,Td,beta,tfinal,JdiPlot,JdoPlot,JrPlot)
                end
            else
                errordlg(['L/T = ' num2str(t0) ', this value must be between 0.1 and 2.0'],'Error')
            end
        end
        
        function ploter(app,K,T,a,L,Kp,Ti,Td,beta,tfinal,Jdi,Jdo,Jr)
            ParamPlant=[K;T;L;a];
            alpha=0.1;
            gamma=0;
            ParamContr=[Kp,Ti,Td,alpha,beta,gamma].';
            time = (0:0.01:tfinal).';
            %time = linspace(0, t, 10000).';
            Graficas =Jdo+Jdi+Jr;
            switch Graficas
                case 0
                    do=Jdo*time;
                    di=Jdi*time;
                    r=Jr*time;
                    timePert=[0;0;0];
                case 1
                    di=Jdi*(time>=tfinal/2);
                    do=Jdo*(time>=tfinal/2);
                    r=Jr*(time>=tfinal/2);
                    timePert=[Jdi;Jdo;Jr]*(tfinal/2);
                case 2
                    di=Jdi*(time>=tfinal/3);
                    do=Jdo*(time>=(1+Jdi)*tfinal/3);
                    r=Jr*(time>=(1+Jdo+Jdi)*tfinal/3);
                    timePert=[Jdi*tfinal/3;Jdo*(1+Jdi)*tfinal/3;Jr*(1+Jdo+Jdi)*tfinal/3];
                case 3
                    di=Jdi*(time>=tfinal/4);
                    do=Jdo*(time>=2*tfinal/4);
                    r=Jr*(time>=3*tfinal/4);
                    timePert=[tfinal/4;2*tfinal/4;3*tfinal/4];
            end
            
            x0=zeros(4,1);%Estados iniciales, siempre poner en cero
            %[y,u,x]=SOPTDPIDSimu(ParamPlant,ParamContr,time,r,di,do,x0);
            [y,~,u]=SOPTDPIDSimu(ParamPlant,ParamContr,time,r,di,do,x0);
            switch Graficas
                case 3
                    diIndices=find((time>=timePert(1))&(time<timePert(2)));
                    doIndices=find((time>=timePert(2))&(time<timePert(3)));
                    rIndices=find((time>=timePert(3)));
                case 2
                    if Jr==0
                        diIndices=find((time>=timePert(1))&(time<timePert(2)));
                        doIndices=find((time>=timePert(2)));
                        rIndices=[];
                    elseif Jdo==0
                        diIndices=find((time>=timePert(1))&(time<timePert(3)));
                        doIndices=[];
                        rIndices=find((time>=timePert(3)));
                    else %Jdi=0
                        diIndices=[];
                        doIndices=find((time>=timePert(2))&(time<timePert(3)));
                        rIndices=find((time>=timePert(3)));
                    end
                case 1
                    if Jdi==0
                        diIndices = [];
                    else
                        diIndices=find((time>=timePert(1)));
                    end
                    
                    if Jdo==0
                        doIndices = [];
                    else
                        doIndices=find((time>=timePert(2)));
                    end
                    
                    if Jr==0
                        rIndices = [];
                    else
                        rIndices=find((time>=timePert(3)));
                    end
                otherwise
                    diIndices = [];
                    doIndices = [];
                    rIndices = [];
            end
            % Jdi
            if isempty(diIndices)
                app.JdiValueLabel.Text = '-';
                app.Results.Jdi = nan;
            else
                IAE=trapz(time(diIndices),abs(y(diIndices)));
                app.JdiValueLabel.Text = num2str(IAE);
                app.Results.Jdi = IAE;
            end
            % Jdo
            if isempty(doIndices)
                app.JdoValueLabel.Text = '-';
                app.Results.Jdo = nan;
            else
                IAE=trapz(time(doIndices),abs(y(doIndices)));
                app.JdoValueLabel.Text = num2str(IAE);
                app.Results.Jdo = IAE;
            end
            % Jr
            if isempty(rIndices)
                app.JrValueLabel.Text = '-';
                app.Results.Jr = nan;
            else
                IAE=trapz(time(rIndices),abs(1-y(rIndices)));
                app.JrValueLabel.Text = num2str(IAE);
                app.Results.Jr = IAE;
            end
            % Calculo Ms
            ParamPlant=[K;T;L;a];
            ParamControl=[Kp,Ti,Td,0.1]; %parguideametros del controlador
            Ms=MsCalc2(ParamPlant,ParamControl);
            app.MsValueLabel.Text = num2str(Ms);
            app.Results.Ms = Ms;
            if app.ControleffortCheckBox.Value
                plot(app.UIAxes,time,[y,u],'LineWidth',1.5);
                legend(app.UIAxes,{'Output','Control effort'})
            else
                plot(app.UIAxes,time,y,'LineWidth',1.5);
                legend(app.UIAxes,{'Output'})
            end
            
        end
        
        function [Param,Jmin] = buscar2Fun(~,JdiVec,JrVec,KpVec,TiVec,TdVec,betaVec,Jdimax,Jrmax)
            if (Jrmax == min([Jdimax,Jrmax])) %&& Jdomax~=0 && Jrmax~=0 %Jdimax==0 && Jdomax~=0 && Jrmax~=0
                i=JdiVec<=Jdimax;
                KpJ=KpVec(i);
                TiJ=TiVec(i);
                TdJ=TdVec(i);
                betaJ = betaVec(i);
                J=JrVec(i);
                [Jmin,k]=min(J);
                if Jmin<= Jrmax
                    Kp=KpJ(k);
                    Ti=TiJ(k);
                    Td=TdJ(k);
                    beta=betaJ(k);
                    Param=[Kp;Ti;Td;beta];
                else
                    Param = [];
                end                
            else
                i=JrVec<=Jrmax;
                KpJ=KpVec(i);
                TiJ=TiVec(i);
                TdJ=TdVec(i);
                betaJ = betaVec(i);
                J=JdiVec(i);
                [Jmin,k]=min(J);
                if Jmin <= Jdimax
                    Kp=KpJ(k);
                    Ti=TiJ(k);
                    Td=TdJ(k);
                    beta=betaJ(k);
                    Param=[Kp;Ti;Td;beta];
                else
                    Param = [];
                end 
            end
        end
        
        function [kp,zi,zd,B] = interpolarControl2Fun(app,t0,a,Ms,Jdimax,Jrmax)
            t0Buscar=[floor(t0/0.1),ceil(t0/0.1)]*0.1;
            aBuscar=[floor(a/0.1),ceil(a/0.1)]*0.1;
            %
            %Primera interpolacion: a con t0 contante
            if aBuscar(1) ~= aBuscar(2)
                if t0Buscar(1) ~= t0Buscar(2)
                    [JdiVec_t01_a1,~,JrVec_t01_a1,KpVec_t01_a1,TiVec_t01_a1,TdVec_t01_a1,betaVec_t01_a1]=seleccionar(app,t0Buscar(1),aBuscar(1),Ms);
                    [JdiVec_t02_a1,~,JrVec_t02_a1,KpVec_t02_a1,TiVec_t02_a1,TdVec_t02_a1,betaVec_t02_a1]=seleccionar(app,t0Buscar(2),aBuscar(1),Ms);
                    Param_t01_a1=buscar2Fun(app,JdiVec_t01_a1,JrVec_t01_a1,KpVec_t01_a1,TiVec_t01_a1,TdVec_t01_a1,betaVec_t01_a1,Jdimax,Jrmax);
                    Param_t02_a1=buscar2Fun(app,JdiVec_t02_a1,JrVec_t02_a1,KpVec_t02_a1,TiVec_t02_a1,TdVec_t02_a1,betaVec_t02_a1,Jdimax,Jrmax);
                    %
                    [JdiVec_t01_a2,~,JrVec_t01_a2,KpVec_t01_a2,TiVec_t01_a2,TdVec_t01_a2,betaVec_t01_a2]=seleccionar(app,t0Buscar(1),aBuscar(2),Ms);
                    [JdiVec_t02_a2,~,JrVec_t02_a2,KpVec_t02_a2,TiVec_t02_a2,TdVec_t02_a2,betaVec_t02_a2]=seleccionar(app,t0Buscar(2),aBuscar(2),Ms);
                    Param_t01_a2=buscar2Fun(app,JdiVec_t01_a2,JrVec_t01_a2,KpVec_t01_a2,TiVec_t01_a2,TdVec_t01_a2,betaVec_t01_a2,Jdimax,Jrmax);
                    Param_t02_a2=buscar2Fun(app,JdiVec_t02_a2,JrVec_t02_a2,KpVec_t02_a2,TiVec_t02_a2,TdVec_t02_a2,betaVec_t02_a2,Jdimax,Jrmax);
                    %
                    Param_t01=interpSimple(app,aBuscar(1),Param_t01_a1,aBuscar(2),Param_t01_a2,a);
                    Param_t02=interpSimple(app,aBuscar(1),Param_t02_a1,aBuscar(2),Param_t02_a2,a);
                    Param=interpSimple(app,t0Buscar(1),Param_t01,t0Buscar(2),Param_t02,t0);
                else
                    [JdiVec_t01_a1,~,JrVec_t01_a1,KpVec_t01_a1,TiVec_t01_a1,TdVec_t01_a1,betaVec_t01_a1]=seleccionar(app,t0Buscar(1),aBuscar(1),Ms);
                    [JdiVec_t01_a2,~,JrVec_t01_a2,KpVec_t01_a2,TiVec_t01_a2,TdVec_t01_a2,betaVec_t01_a2]=seleccionar(app,t0Buscar(1),aBuscar(2),Ms);
                    Param_t01_a1=buscar2Fun(app,JdiVec_t01_a1,JrVec_t01_a1,KpVec_t01_a1,TiVec_t01_a1,TdVec_t01_a1,betaVec_t01_a1,Jdimax,Jrmax);
                    Param_t01_a2=buscar2Fun(app,JdiVec_t01_a2,JrVec_t01_a2,KpVec_t01_a2,TiVec_t01_a2,TdVec_t01_a2,betaVec_t01_a2,Jdimax,Jrmax);
                    Param=interpSimple(app,aBuscar(1),Param_t01_a1,aBuscar(2),Param_t01_a2,a);
                end
            else
                if t0Buscar(1) ~= t0Buscar(2)
                    [JdiVec_t01_a1,~,JrVec_t01_a1,KpVec_t01_a1,TiVec_t01_a1,TdVec_t01_a1,betaVec_t01_a1]=seleccionar(app,t0Buscar(1),aBuscar(1),Ms);
                    [JdiVec_t02_a1,~,JrVec_t02_a1,KpVec_t02_a1,TiVec_t02_a1,TdVec_t02_a1,betaVec_t02_a1]=seleccionar(app,t0Buscar(2),aBuscar(1),Ms);
                    Param_t01_a1=buscar2Fun(app,JdiVec_t01_a1,JrVec_t01_a1,KpVec_t01_a1,TiVec_t01_a1,TdVec_t01_a1,betaVec_t01_a1,Jdimax,Jrmax);
                    Param_t02_a1=buscar2Fun(app,JdiVec_t02_a1,JrVec_t02_a1,KpVec_t02_a1,TiVec_t02_a1,TdVec_t02_a1,betaVec_t02_a1,Jdimax,Jrmax);
                    Param=interpSimple(app,t0Buscar(1),Param_t01_a1,t0Buscar(2),Param_t02_a1,t0);
                else
                    [JdiVec_t01_a1,~,JrVec_t01_a1,KpVec_t01_a1,TiVec_t01_a1,TdVec_t01_a1,betaVec_t01_a1]=seleccionar(app,t0Buscar(1),aBuscar(1),Ms);
                    Param=buscar2Fun(app,JdiVec_t01_a1,JrVec_t01_a1,KpVec_t01_a1,TiVec_t01_a1,TdVec_t01_a1,betaVec_t01_a1,Jdimax,Jrmax);
                end
            end
            if isempty(Param)
                kp = nan;
                zi = nan;
                zd = nan;
                B = nan;
            else
                kp = Param(1);
                zi = Param(2);
                zd = Param(3);
                B = Param(4);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plotStepResp(app)
            updateTuning(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MOOTuningUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {201, '1x', 214};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Value changed function: kEditField
        function kEditFieldValueChanged(app, event)
            %value = app.kEditField.Value;
            plotStepResp(app)
            %updateTuning(app)
        end

        % Value changed function: TEditField
        function TEditFieldValueChanged(app, event)
            %value = app.TEditField.Value;
            plotStepResp(app)
            %updateTuning(app)
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            %value = app.aEditField.Value;
            plotStepResp(app)
            %updateTuning(app)
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            %value = app.LEditField.Value;
            plotStepResp(app)
            %updateTuning(app)
        end

        % Value changed function: JdiSlider
        function JdiSliderValueChanged(app, event)
            %value = app.JdiSlider.Value;
            %app.LabelJdiSlider.Text = num2str(value);
            updateTuning(app)
        end

        % Value changed function: JdoSlider
        function JdoSliderValueChanged(app, event)
            %value = app.JdoSlider.Value;
            %app.LabelJdoSlider.Text = num2str(value);
            updateTuning(app)
        end

        % Value changed function: JrSlider
        function JrSliderValueChanged(app, event)
            %value = app.JrSlider.Value;
            %app.LabelJrSlider.Text = num2str(value);
            updateTuning(app)
        end

        % Value changed function: ServoresponseCheckBox
        function ServoresponseCheckBoxValueChanged(app, event)
            %value = app.ServoresponseCheckBox.Value;
            updateTuning(app)
        end

        % Value changed function: 
        % InputdisturbanceregulationresponseCheckBox
        function InputdisturbanceregulationresponseCheckBoxValueChanged(app, event)
            %value = app.InputdisturbanceregulationresponseCheckBox.Value;
            updateTuning(app)
        end

        % Value changed function: 
        % OutputdisturbanceregullationresponseCheckBox
        function OutputdisturbanceregullationresponseCheckBoxValueChanged(app, event)
            %value = app.OutputdisturbanceregullationresponseCheckBox.Value;
            updateTuning(app)
        end

        % Value changed function: ControleffortCheckBox
        function ControleffortCheckBoxValueChanged(app, event)
            %value = app.ControleffortCheckBox.Value;
            updateTuning(app)
        end

        % Value changed function: FinaltimeEditField
        function FinaltimeEditFieldValueChanged(app, event)
            %value = app.FinaltimeEditField.Value;
            updateTuning(app)
        end

        % Value changed function: DisturbancesDropDown
        function DisturbancesDropDownValueChanged(app, event)
            value = app.DisturbancesDropDown.Value;
            if strcmp(value,'Jdi and Jr')
                app.JdoSlider.Value = 1;
                app.JdoSlider.Enable = false;
                app.OutputdisturbanceregullationresponseCheckBox.Value = false;
                app.OutputdisturbanceregullationresponseCheckBox.Enable = false;
            else
                app.JdoSlider.Enable = true;
                app.OutputdisturbanceregullationresponseCheckBox.Value = true;
                app.OutputdisturbanceregullationresponseCheckBox.Enable = true;
            end
            updateTuning(app)
        end

        % Value changed function: MsDropDown
        function MsDropDownValueChanged(app, event)
            %value = app.MsDropDown.Value;
            updateTuning(app)
        end

        % Button pushed function: ExportTuningButton
        function ExportTuningButtonPushed(app, event)
            Resultapp = app.Results;
            assignin('base', 'MOOResults', Resultapp);
        end

        % Value changing function: JdiSlider
        function JdiSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LabelJdiSlider.Text = num2str(changingValue,3);
        end

        % Value changing function: JdoSlider
        function JdoSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LabelJdoSlider.Text = num2str(changingValue,3);
        end

        % Value changing function: JrSlider
        function JrSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LabelJrSlider.Text = num2str(changingValue,3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MOOTuningUIFigure = uifigure('Visible', 'off');
            app.MOOTuningUIFigure.Name = 'MOOTuning';
            app.MOOTuningUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MOOTuningUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlantparametersLabel = uilabel(app.LeftPanel);
            app.PlantparametersLabel.Text = 'Plant parameters';
            app.kEditFieldLabel = uilabel(app.LeftPanel);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.LeftPanel, 'numeric');
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @kEditFieldValueChanged, true);
            app.kEditField.Value = 1;
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'equation.png';
            app.TEditFieldLabel = uilabel(app.LeftPanel);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TEditField.Limits = [0 Inf];
            app.TEditField.ValueChangedFcn = createCallbackFcn(app, @TEditFieldValueChanged, true);
            app.TEditField.Value = 1;
            app.aEditFieldLabel = uilabel(app.LeftPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.LeftPanel, 'numeric');
            app.aEditField.Limits = [0 1];
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.aEditField.Value = 0.4;
            app.LEditFieldLabel = uilabel(app.LeftPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LEditField.Limits = [0 Inf];
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.LEditField.Value = 0.5;
            app.UIAxes2 = uiaxes(app.LeftPanel);
            title(app.UIAxes2, 'Step response')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Amplitude')
            app.CenterPanel = uipanel(app.GridLayout);
            app.MOOTuningLabel = uilabel(app.CenterPanel);
            app.MOOTuningLabel.Text = 'MOOTuning';
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Control Response')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            app.ServoresponseCheckBox = uicheckbox(app.CenterPanel);
            app.ServoresponseCheckBox.ValueChangedFcn = createCallbackFcn(app, @ServoresponseCheckBoxValueChanged, true);
            app.ServoresponseCheckBox.Text = 'Servo response';
            app.ServoresponseCheckBox.Value = true;
            app.InputdisturbanceregulationresponseCheckBox = uicheckbox(app.CenterPanel);
            app.InputdisturbanceregulationresponseCheckBox.ValueChangedFcn = createCallbackFcn(app, @InputdisturbanceregulationresponseCheckBoxValueChanged, true);
            app.InputdisturbanceregulationresponseCheckBox.Text = 'Input disturbance regulation response';
            app.InputdisturbanceregulationresponseCheckBox.Value = true;
            app.OutputdisturbanceregullationresponseCheckBox = uicheckbox(app.CenterPanel);
            app.OutputdisturbanceregullationresponseCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputdisturbanceregullationresponseCheckBoxValueChanged, true);
            app.OutputdisturbanceregullationresponseCheckBox.Text = 'Output disturbance regullation response';
            app.OutputdisturbanceregullationresponseCheckBox.Value = true;
            app.FinaltimeEditFieldLabel = uilabel(app.CenterPanel);
            app.FinaltimeEditFieldLabel.Text = 'Final time';
            app.FinaltimeEditField = uieditfield(app.CenterPanel, 'numeric');
            app.FinaltimeEditField.Limits = [0 Inf];
            app.FinaltimeEditField.ValueChangedFcn = createCallbackFcn(app, @FinaltimeEditFieldValueChanged, true);
            app.FinaltimeEditField.Value = 100;
            app.KpLabel = uilabel(app.CenterPanel);
            app.KpLabel.Text = 'Kp:';
            app.TiLabel = uilabel(app.CenterPanel);
            app.TiLabel.Text = 'Ti:';
            app.TdLabel = uilabel(app.CenterPanel);
            app.TdLabel.Text = 'Td:';
            app.betaLabel = uilabel(app.CenterPanel);
            app.betaLabel.Text = 'beta:';
            app.KpValueLabel = uilabel(app.CenterPanel);
            app.KpValueLabel.Text = '-';
            app.TiValueLabel = uilabel(app.CenterPanel);
            app.TiValueLabel.Text = '-';
            app.TdValueLabel = uilabel(app.CenterPanel);
            app.TdValueLabel.Text = '-';
            app.betaValueLabel = uilabel(app.CenterPanel);
            app.betaValueLabel.Text = '-';
            app.ControleffortCheckBox = uicheckbox(app.CenterPanel);
            app.ControleffortCheckBox.ValueChangedFcn = createCallbackFcn(app, @ControleffortCheckBoxValueChanged, true);
            app.ControleffortCheckBox.Text = 'Control effort';
            app.JdiLabel = uilabel(app.CenterPanel);
            app.JdiLabel.Text = 'Jdi:';
            app.JdoLabel = uilabel(app.CenterPanel);
            app.JdoLabel.Text = 'Jdo:';
            app.JdiValueLabel = uilabel(app.CenterPanel);
            app.JdiValueLabel.Text = '-';
            app.JdoValueLabel = uilabel(app.CenterPanel);
            app.JdoValueLabel.Text = '-';
            app.JrLabel = uilabel(app.CenterPanel);
            app.JrLabel.Text = 'Jr:';
            app.MsLabel = uilabel(app.CenterPanel);
            app.MsLabel.Text = 'Ms:';
            app.JrValueLabel = uilabel(app.CenterPanel);
            app.JrValueLabel.Text = '-';
            app.MsValueLabel = uilabel(app.CenterPanel);
            app.MsValueLabel.Text = '-';
            app.RightPanel = uipanel(app.GridLayout);
            app.JdiSliderLabel = uilabel(app.RightPanel);
            app.JdiSliderLabel.Text = 'Jdi';
            app.JdiSlider = uislider(app.RightPanel);
            app.JdiSlider.Limits = [0 1];
            app.JdiSlider.ValueChangedFcn = createCallbackFcn(app, @JdiSliderValueChanged, true);
            app.JdiSlider.ValueChangingFcn = createCallbackFcn(app, @JdiSliderValueChanging, true);
            app.JdiSlider.Value = 1;
            app.JdoSliderLabel = uilabel(app.RightPanel);
            app.JdoSliderLabel.Text = 'Jdo';
            app.JdoSlider = uislider(app.RightPanel);
            app.JdoSlider.Limits = [0 1];
            app.JdoSlider.ValueChangedFcn = createCallbackFcn(app, @JdoSliderValueChanged, true);
            app.JdoSlider.ValueChangingFcn = createCallbackFcn(app, @JdoSliderValueChanging, true);
            app.JdoSlider.Value = 1;
            app.JrSliderLabel = uilabel(app.RightPanel);
            app.JrSliderLabel.Text = 'Jr';
            app.JrSlider = uislider(app.RightPanel);
            app.JrSlider.Limits = [0 1];
            app.JrSlider.ValueChangedFcn = createCallbackFcn(app, @JrSliderValueChanged, true);
            app.JrSlider.ValueChangingFcn = createCallbackFcn(app, @JrSliderValueChanging, true);
            app.JrSlider.Value = 1;
            app.ControllertuningLabel = uilabel(app.RightPanel);
            app.ControllertuningLabel.Text = 'Controller tuning';
            app.ExportTuningButton = uibutton(app.RightPanel, 'push');
            app.ExportTuningButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTuningButtonPushed, true);
            app.ExportTuningButton.Text = 'Export Tuning';
            app.DisturbancesDropDownLabel = uilabel(app.RightPanel);
            app.DisturbancesDropDownLabel.Text = 'Disturbances';
            app.DisturbancesDropDown = uidropdown(app.RightPanel);
            app.DisturbancesDropDown.Items = {'All', 'Jdi and Jr'};
            app.DisturbancesDropDown.ValueChangedFcn = createCallbackFcn(app, @DisturbancesDropDownValueChanged, true);
            app.DisturbancesDropDown.Value = 'All';
            app.MsDropDownLabel = uilabel(app.RightPanel);
            app.MsDropDownLabel.Text = 'Ms';
            app.MsDropDown = uidropdown(app.RightPanel);
            app.MsDropDown.Items = {'> 2.0', '2.0', '1.8', '1.6'};
            app.MsDropDown.ValueChangedFcn = createCallbackFcn(app, @MsDropDownValueChanged, true);
            app.MsDropDown.Value = '> 2.0';
            app.LabelJdiSlider = uilabel(app.RightPanel);
            app.LabelJdiSlider.Text = '1';
            app.LabelJdoSlider = uilabel(app.RightPanel);
            app.LabelJdoSlider.Text = '1';
            app.LabelJrSlider = uilabel(app.RightPanel);
            app.LabelJrSlider.Text = '1';
            % Show the figure after all components are created
            app.MOOTuningUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MOOTuning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MOOTuningUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MOOTuningUIFigure)
        end
    end
end